classdef GUI_CFSTCOLUMNS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OnlyAxialLoadisConsideredLabel  matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        CalculationsasperEN1994112004ELabel  matlab.ui.control.Label
        NoteLabel                       matlab.ui.control.Label
        AuthorsMrANTONYRAJTDrHJaneHelenaHLabel  matlab.ui.control.Label
        kNLabel_5                       matlab.ui.control.Label
        PdEditField                     matlab.ui.control.NumericEditField
        PdEditFieldLabel                matlab.ui.control.Label
        kNmLabel_4                      matlab.ui.control.Label
        MdEditField                     matlab.ui.control.NumericEditField
        MdEditFieldLabel                matlab.ui.control.Label
        mmLabel                         matlab.ui.control.Label
        mm3Label_4                      matlab.ui.control.Label
        mm3Label_3                      matlab.ui.control.Label
        ZpnConcreteEditField            matlab.ui.control.NumericEditField
        ZpnConcreteEditFieldLabel       matlab.ui.control.Label
        ZpnSteelEditField               matlab.ui.control.NumericEditField
        ZpnSteelEditFieldLabel          matlab.ui.control.Label
        hnEditField                     matlab.ui.control.NumericEditField
        hnEditFieldLabel                matlab.ui.control.Label
        kNmLabel_3                      matlab.ui.control.Label
        kNLabel_4                       matlab.ui.control.Label
        McEditField                     matlab.ui.control.NumericEditField
        McEditFieldLabel                matlab.ui.control.Label
        PcEditField                     matlab.ui.control.NumericEditField
        PcEditFieldLabel                matlab.ui.control.Label
        kNmLabel_2                      matlab.ui.control.Label
        kNLabel_3                       matlab.ui.control.Label
        MbEditField                     matlab.ui.control.NumericEditField
        MbEditFieldLabel                matlab.ui.control.Label
        PbEditField                     matlab.ui.control.NumericEditField
        PbEditFieldLabel                matlab.ui.control.Label
        mm3Label_2                      matlab.ui.control.Label
        mm3Label                        matlab.ui.control.Label
        ZpConcreteEditField             matlab.ui.control.NumericEditField
        ZpConcreteEditFieldLabel        matlab.ui.control.Label
        PlasticSectionModulusLabel      matlab.ui.control.Label
        ZpSteelEditField                matlab.ui.control.NumericEditField
        ZpSteelEditFieldLabel           matlab.ui.control.Label
        kNmLabel                        matlab.ui.control.Label
        kNLabel_2                       matlab.ui.control.Label
        MaEditField                     matlab.ui.control.NumericEditField
        MaEditFieldLabel                matlab.ui.control.Label
        PaEditField                     matlab.ui.control.NumericEditField
        PaEditFieldLabel                matlab.ui.control.Label
        PartialSafetyFactorforConcreteEditField  matlab.ui.control.NumericEditField
        PartialSafetyFactorforConcreteEditFieldLabel  matlab.ui.control.Label
        PartialSafetyFactorforSteelEditField  matlab.ui.control.NumericEditField
        PartialSafetyFactorforSteelEditFieldLabel  matlab.ui.control.Label
        TYPEOFLOADINGDropDown           matlab.ui.control.DropDown
        TYPEOFLOADINGDropDownLabel      matlab.ui.control.Label
        RELATIVESLENDERNESSEditField    matlab.ui.control.NumericEditField
        RELATIVESLENDERNESSEditFieldLabel  matlab.ui.control.Label
        STEELCONTRIBUTIONRATIOEditField  matlab.ui.control.NumericEditField
        STEELCONTRIBUTIONRATIOEditFieldLabel  matlab.ui.control.Label
        TopInternalDiametermmEditField  matlab.ui.control.NumericEditField
        TopInternalDiametermmEditFieldLabel  matlab.ui.control.Label
        TaperAngleEditField             matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        BottomInternalDiametermmEditField  matlab.ui.control.NumericEditField
        BottomInternalDiametermmEditFieldLabel  matlab.ui.control.Label
        EFFECTIVEFLEXURALSTIFFNESSEIeffEditField  matlab.ui.control.NumericEditField
        EFFECTIVEFLEXURALSTIFFNESSEIeffEditFieldLabel  matlab.ui.control.Label
        ModulusofElasticityofConcreteNmm2EditField  matlab.ui.control.NumericEditField
        ModulusofElasticityofConcreteNmm2EditFieldLabel  matlab.ui.control.Label
        ModulusofElasticityofStructuralSteelNmm2EditField  matlab.ui.control.NumericEditField
        ModulusofElasticityofStructuralSteelNmm2EditFieldLabel  matlab.ui.control.Label
        EccentricitymmEditField         matlab.ui.control.NumericEditField
        EccentricitymmEditFieldLabel    matlab.ui.control.Label
        HeightoftheCFSTColumnmmEditField  matlab.ui.control.NumericEditField
        HeightoftheCFSTColumnmmEditFieldLabel  matlab.ui.control.Label
        CylinderCompressiveStrengthofConcreteNmm2EditField  matlab.ui.control.NumericEditField
        CylinderCompressiveStrengthofConcreteNmm2EditFieldLabel  matlab.ui.control.Label
        ULTIMATEAXIALLOADEditField      matlab.ui.control.NumericEditField
        ULTIMATEAXIALLOADEditFieldLabel  matlab.ui.control.Label
        ThicknessofSteelTubemmEditField  matlab.ui.control.NumericEditField
        ThicknessofSteelTubemmEditFieldLabel  matlab.ui.control.Label
        AVGDIAMETERmmEditField          matlab.ui.control.NumericEditField
        AVGDIAMETERmmEditFieldLabel     matlab.ui.control.Label
        YeildStrengthofStructuralSteelNmm2EditField  matlab.ui.control.NumericEditField
        YeildStrengthofStructuralSteelNmm2EditFieldLabel  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Nmm2Label                       matlab.ui.control.Label
        kNLabel                         matlab.ui.control.Label
        RESULTButton                    matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RESULTButton
        function RESULTButtonPushed(app, event)
           a=tan(app.TaperAngleEditField.Value * (3.1415/180));
           b=(a*app.HeightoftheCFSTColumnmmEditField.Value);
           app.TopInternalDiametermmEditField.Value= ((app.BottomInternalDiametermmEditField.Value)-(2*b));
           app.AVGDIAMETERmmEditField.Value = (app.TopInternalDiametermmEditField.Value+app.BottomInternalDiametermmEditField.Value)/2;
            f=app.TopInternalDiametermmEditField.Value;
            g=app.BottomInternalDiametermmEditField.Value;
            h=app.HeightoftheCFSTColumnmmEditField.Value;
            x =app.AVGDIAMETERmmEditField.Value;
            y =app.ThicknessofSteelTubemmEditField.Value;
            s=app.PartialSafetyFactorforSteelEditField.Value;
            r=app.PartialSafetyFactorforConcreteEditField.Value;
            z =app.YeildStrengthofStructuralSteelNmm2EditField.Value/s;
            x=(x/2);
            c =app.AVGDIAMETERmmEditField.Value;
            d =(app.CylinderCompressiveStrengthofConcreteNmm2EditField.Value/r);
            c=c/2;
            o=app.AVGDIAMETERmmEditField.Value;
            r1=(8*y*z);
            t=(2*o*d);
            a1=r1+t;
            Ia = (3.1415/64)*(((app.AVGDIAMETERmmEditField.Value)^4)-((app.AVGDIAMETERmmEditField.Value-2*app.ThicknessofSteelTubemmEditField.Value)^4));
            Ic = (3.1415/64)*((app.AVGDIAMETERmmEditField.Value-2*app.ThicknessofSteelTubemmEditField.Value)^4);
            ke=0.6;
            app.EFFECTIVEFLEXURALSTIFFNESSEIeffEditField.Value = (app.ModulusofElasticityofStructuralSteelNmm2EditField.Value*Ia)+(ke*app.ModulusofElasticityofConcreteNmm2EditField.Value*Ic);
            p=((3.1415^2)*(app.EFFECTIVEFLEXURALSTIFFNESSEIeffEditField.Value)/(h^2));
            q=(g-f)/(f);
            l=p*((2*q)+1);
            m=(((3.1415*((x+y)^2-(x)^2))*z))+(((3.1415*((c)^2))*d));
            app.RELATIVESLENDERNESSEditField.Value=sqrt(m/l);
            w=(app.EccentricitymmEditField.Value/app.AVGDIAMETERmmEditField.Value);
            u=0;
            v=0;
          if  app.EccentricitymmEditField.Value==0
               u=0.25*(3+2*app.RELATIVESLENDERNESSEditField.Value);
               v=(4.9-18.5*app.RELATIVESLENDERNESSEditField.Value+17*(app.RELATIVESLENDERNESSEditField.Value)^2);
               i=u;
               j=v;
          else 0<w<=0.1;
               i=u+((1-u)*(10*w));
               j=v*(1-10*w);
          if w>0.1;
               i=1;
               j=0;
          end
          end
           
            app.ULTIMATEAXIALLOADEditField.Value = ((i)*((3.1415*((x+y)^2-(x)^2)*z))+(((3.1415*((c)^2))*d))*(1+j*(app.ThicknessofSteelTubemmEditField.Value/app.AVGDIAMETERmmEditField.Value)*(app.YeildStrengthofStructuralSteelNmm2EditField.Value/app.CylinderCompressiveStrengthofConcreteNmm2EditField.Value)))/1000;
            app.STEELCONTRIBUTIONRATIOEditField.Value = ((3.1415*((x+y)^2-(x)^2))*z)/(app.ULTIMATEAXIALLOADEditField.Value*1000);
            app.PaEditField.Value=((i)*((3.1415*((x+y)^2-(x)^2)*z))+(((3.1415*((c)^2))*d))*(1+j*(app.ThicknessofSteelTubemmEditField.Value/app.AVGDIAMETERmmEditField.Value)*(app.YeildStrengthofStructuralSteelNmm2EditField.Value/app.CylinderCompressiveStrengthofConcreteNmm2EditField.Value)))/1000;
            app.ZpSteelEditField.Value=(((o+2*y)^3)-(o^3))/6;
            app.ZpConcreteEditField.Value=((o^3))/6;
            x1=app.ZpSteelEditField.Value;
            x2=app.ZpConcreteEditField.Value;
            app.PbEditField.Value=((((3.1415*o^2)/4)*d)/(2))/1000;
            a=(2*app.PbEditField.Value)*1000;
            app.MaEditField.Value=0;
            app.MbEditField.Value=((app.ZpSteelEditField.Value*z)+(0.5*app.ZpConcreteEditField.Value*d))/1000000;
            app.PcEditField.Value=(2*app.PbEditField.Value);
            app.hnEditField.Value=(a/a1);
            app.ZpnSteelEditField.Value=((2*y*(2*app.hnEditField.Value)^2))/4;
            app.ZpnConcreteEditField.Value=((o*(2*app.hnEditField.Value)^2))/4;
            y1=app.ZpnSteelEditField.Value;
            y2=app.ZpnConcreteEditField.Value;
            app.McEditField.Value=(((x1-y1)*z)+(0.5*(x2-y2)*d))/1000000;
            z1=app.McEditField.Value;
            app.MdEditField.Value=z1;
            app.PdEditField.Value=0;
            u1=app.MaEditField.Value;
            u2=app.MbEditField.Value;
            u3=app.McEditField.Value;
            u4=app.MdEditField.Value;
            v1=app.PaEditField.Value;
            v2=app.PbEditField.Value;
            v3=app.PcEditField.Value;
            v4=app.PdEditField.Value;
            Y=[u1 u3 u2 u4];
            X=[v1 v3 v2 v4];
            plot(app.UIAxes,Y,X);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
     
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'INTERACTION CURVE')
            xlabel(app.UIAxes, 'Moment(kN.m)')
            ylabel(app.UIAxes, 'Axial Load(kN)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.RESULTButton = uibutton(app.UIFigure, 'push');
            app.RESULTButton.ButtonPushedFcn = createCallbackFcn(app, @RESULTButtonPushed, true);
            app.RESULTButton.Text = 'RESULT';
            app.kNLabel = uilabel(app.UIFigure);
            app.kNLabel.Text = 'kN';
            app.Nmm2Label = uilabel(app.UIFigure);
            app.Nmm2Label.Text = 'Nmm2';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'THEORETICAL CALCULATIONS OF LOAD CARRYING CAPACITY OF STRAIGHT AND TAPERED CFST COLUMNS(CIRCULAR SECTIONS)';
            app.YeildStrengthofStructuralSteelNmm2EditFieldLabel = uilabel(app.UIFigure);
            app.YeildStrengthofStructuralSteelNmm2EditFieldLabel.Text = 'Yeild Strength of Structural Steel(N/mm2)';
            app.YeildStrengthofStructuralSteelNmm2EditField = uieditfield(app.UIFigure, 'numeric');
            app.YeildStrengthofStructuralSteelNmm2EditField.AllowEmpty = 'on';
            app.YeildStrengthofStructuralSteelNmm2EditField.Value = [];
            app.AVGDIAMETERmmEditFieldLabel = uilabel(app.UIFigure);
            app.AVGDIAMETERmmEditFieldLabel.Text = 'AVG DIAMETER(mm)';
            app.AVGDIAMETERmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.AVGDIAMETERmmEditField.AllowEmpty = 'on';
            app.AVGDIAMETERmmEditField.Editable = 'off';
            app.AVGDIAMETERmmEditField.Value = [];
            app.ThicknessofSteelTubemmEditFieldLabel = uilabel(app.UIFigure);
            app.ThicknessofSteelTubemmEditFieldLabel.Text = 'Thickness of Steel Tube(mm)';
            app.ThicknessofSteelTubemmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThicknessofSteelTubemmEditField.AllowEmpty = 'on';
            app.ThicknessofSteelTubemmEditField.Value = [];
            app.ULTIMATEAXIALLOADEditFieldLabel = uilabel(app.UIFigure);
            app.ULTIMATEAXIALLOADEditFieldLabel.Text = 'ULTIMATE AXIAL LOAD';
            app.ULTIMATEAXIALLOADEditField = uieditfield(app.UIFigure, 'numeric');
            app.ULTIMATEAXIALLOADEditField.AllowEmpty = 'on';
            app.ULTIMATEAXIALLOADEditField.Editable = 'off';
            app.ULTIMATEAXIALLOADEditField.Value = [];
            app.CylinderCompressiveStrengthofConcreteNmm2EditFieldLabel = uilabel(app.UIFigure);
            app.CylinderCompressiveStrengthofConcreteNmm2EditFieldLabel.Text = 'Cylinder Compressive Strength of Concrete(N/mm2)';
            app.CylinderCompressiveStrengthofConcreteNmm2EditField = uieditfield(app.UIFigure, 'numeric');
            app.CylinderCompressiveStrengthofConcreteNmm2EditField.AllowEmpty = 'on';
            app.CylinderCompressiveStrengthofConcreteNmm2EditField.Value = [];
            app.HeightoftheCFSTColumnmmEditFieldLabel = uilabel(app.UIFigure);
            app.HeightoftheCFSTColumnmmEditFieldLabel.Text = 'Height of the CFST Column(mm)';
            app.HeightoftheCFSTColumnmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightoftheCFSTColumnmmEditField.AllowEmpty = 'on';
            app.HeightoftheCFSTColumnmmEditField.Value = [];
            app.EccentricitymmEditFieldLabel = uilabel(app.UIFigure);
            app.EccentricitymmEditFieldLabel.Text = 'Eccentricity (mm)';
            app.EccentricitymmEditField = uieditfield(app.UIFigure, 'numeric');
            app.EccentricitymmEditField.Editable = 'off';
            app.ModulusofElasticityofStructuralSteelNmm2EditFieldLabel = uilabel(app.UIFigure);
            app.ModulusofElasticityofStructuralSteelNmm2EditFieldLabel.Text = 'Modulus of Elasticity of Structural Steel(N/mm2)';
            app.ModulusofElasticityofStructuralSteelNmm2EditField = uieditfield(app.UIFigure, 'numeric');
            app.ModulusofElasticityofStructuralSteelNmm2EditField.AllowEmpty = 'on';
            app.ModulusofElasticityofStructuralSteelNmm2EditField.Value = [];
            app.ModulusofElasticityofConcreteNmm2EditFieldLabel = uilabel(app.UIFigure);
            app.ModulusofElasticityofConcreteNmm2EditFieldLabel.Text = 'Modulus of Elasticity of Concrete(N/mm2)';
            app.ModulusofElasticityofConcreteNmm2EditField = uieditfield(app.UIFigure, 'numeric');
            app.ModulusofElasticityofConcreteNmm2EditField.AllowEmpty = 'on';
            app.ModulusofElasticityofConcreteNmm2EditField.Value = [];
            app.EFFECTIVEFLEXURALSTIFFNESSEIeffEditFieldLabel = uilabel(app.UIFigure);
            app.EFFECTIVEFLEXURALSTIFFNESSEIeffEditFieldLabel.Text = 'EFFECTIVE FLEXURAL STIFFNESS(EIeff)';
            app.EFFECTIVEFLEXURALSTIFFNESSEIeffEditField = uieditfield(app.UIFigure, 'numeric');
            app.EFFECTIVEFLEXURALSTIFFNESSEIeffEditField.AllowEmpty = 'on';
            app.EFFECTIVEFLEXURALSTIFFNESSEIeffEditField.Editable = 'off';
            app.EFFECTIVEFLEXURALSTIFFNESSEIeffEditField.Value = [];
            app.BottomInternalDiametermmEditFieldLabel = uilabel(app.UIFigure);
            app.BottomInternalDiametermmEditFieldLabel.Text = 'Bottom Internal Diameter(mm)';
            app.BottomInternalDiametermmEditField = uieditfield(app.UIFigure, 'numeric');
            app.BottomInternalDiametermmEditField.AllowEmpty = 'on';
            app.BottomInternalDiametermmEditField.Value = [];
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Taper Angle (θ°)';
            app.TaperAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.TaperAngleEditField.AllowEmpty = 'on';
            app.TaperAngleEditField.Value = [];
            app.TopInternalDiametermmEditFieldLabel = uilabel(app.UIFigure);
            app.TopInternalDiametermmEditFieldLabel.Text = 'Top Internal Diameter(mm)';
            app.TopInternalDiametermmEditField = uieditfield(app.UIFigure, 'numeric');
            app.TopInternalDiametermmEditField.AllowEmpty = 'on';
            app.TopInternalDiametermmEditField.Editable = 'off';
            app.TopInternalDiametermmEditField.Value = [];
            app.STEELCONTRIBUTIONRATIOEditFieldLabel = uilabel(app.UIFigure);
            app.STEELCONTRIBUTIONRATIOEditFieldLabel.Text = 'STEEL CONTRIBUTION RATIO';
            app.STEELCONTRIBUTIONRATIOEditField = uieditfield(app.UIFigure, 'numeric');
            app.STEELCONTRIBUTIONRATIOEditField.AllowEmpty = 'on';
            app.STEELCONTRIBUTIONRATIOEditField.Editable = 'off';
            app.STEELCONTRIBUTIONRATIOEditField.Value = [];
            app.RELATIVESLENDERNESSEditFieldLabel = uilabel(app.UIFigure);
            app.RELATIVESLENDERNESSEditFieldLabel.Text = 'RELATIVE SLENDERNESS';
            app.RELATIVESLENDERNESSEditField = uieditfield(app.UIFigure, 'numeric');
            app.RELATIVESLENDERNESSEditField.AllowEmpty = 'on';
            app.RELATIVESLENDERNESSEditField.Editable = 'off';
            app.RELATIVESLENDERNESSEditField.Value = [];
            app.TYPEOFLOADINGDropDownLabel = uilabel(app.UIFigure);
            app.TYPEOFLOADINGDropDownLabel.Text = 'TYPE OF LOADING';
            app.TYPEOFLOADINGDropDown = uidropdown(app.UIFigure);
            app.TYPEOFLOADINGDropDown.Items = {'AXIAL LOAD'};
            app.TYPEOFLOADINGDropDown.Value = 'AXIAL LOAD';
            app.PartialSafetyFactorforSteelEditFieldLabel = uilabel(app.UIFigure);
            app.PartialSafetyFactorforSteelEditFieldLabel.Text = 'Partial Safety Factor for Steel';
            app.PartialSafetyFactorforSteelEditField = uieditfield(app.UIFigure, 'numeric');
            app.PartialSafetyFactorforSteelEditField.AllowEmpty = 'on';
            app.PartialSafetyFactorforSteelEditField.Value = [];
            app.PartialSafetyFactorforConcreteEditFieldLabel = uilabel(app.UIFigure);
            app.PartialSafetyFactorforConcreteEditFieldLabel.Text = 'Partial Safety Factor for Concrete';
            app.PartialSafetyFactorforConcreteEditField = uieditfield(app.UIFigure, 'numeric');
            app.PartialSafetyFactorforConcreteEditField.AllowEmpty = 'on';
            app.PartialSafetyFactorforConcreteEditField.Value = [];
            app.PaEditFieldLabel = uilabel(app.UIFigure);
            app.PaEditFieldLabel.Text = 'Pa';
            app.PaEditField = uieditfield(app.UIFigure, 'numeric');
            app.PaEditField.AllowEmpty = 'on';
            app.PaEditField.Editable = 'off';
            app.PaEditField.Value = [];
            app.MaEditFieldLabel = uilabel(app.UIFigure);
            app.MaEditFieldLabel.Text = 'Ma';
            app.MaEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaEditField.AllowEmpty = 'on';
            app.MaEditField.Editable = 'off';
            app.MaEditField.Value = [];
            app.kNLabel_2 = uilabel(app.UIFigure);
            app.kNLabel_2.Text = 'kN';
            app.kNmLabel = uilabel(app.UIFigure);
            app.kNmLabel.Text = 'kN.m';
            app.ZpSteelEditFieldLabel = uilabel(app.UIFigure);
            app.ZpSteelEditFieldLabel.Text = 'Zp,Steel';
            app.ZpSteelEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZpSteelEditField.AllowEmpty = 'on';
            app.ZpSteelEditField.Editable = 'off';
            app.ZpSteelEditField.Value = [];
            app.PlasticSectionModulusLabel = uilabel(app.UIFigure);
            app.PlasticSectionModulusLabel.Text = 'Plastic Section Modulus';
            app.ZpConcreteEditFieldLabel = uilabel(app.UIFigure);
            app.ZpConcreteEditFieldLabel.Text = 'Zp,Concrete';
            app.ZpConcreteEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZpConcreteEditField.AllowEmpty = 'on';
            app.ZpConcreteEditField.Editable = 'off';
            app.ZpConcreteEditField.Value = [];
            app.mm3Label = uilabel(app.UIFigure);
            app.mm3Label.Text = 'mm3';
            app.mm3Label_2 = uilabel(app.UIFigure);
            app.mm3Label_2.Text = 'mm3';
            app.PbEditFieldLabel = uilabel(app.UIFigure);
            app.PbEditFieldLabel.Text = 'Pb';
            app.PbEditField = uieditfield(app.UIFigure, 'numeric');
            app.PbEditField.AllowEmpty = 'on';
            app.PbEditField.Editable = 'off';
            app.PbEditField.Value = [];
            app.MbEditFieldLabel = uilabel(app.UIFigure);
            app.MbEditFieldLabel.Text = 'Mb';
            app.MbEditField = uieditfield(app.UIFigure, 'numeric');
            app.MbEditField.AllowEmpty = 'on';
            app.MbEditField.Editable = 'off';
            app.MbEditField.Value = [];
            app.kNLabel_3 = uilabel(app.UIFigure);
            app.kNLabel_3.Text = 'kN';
            app.kNmLabel_2 = uilabel(app.UIFigure);
            app.kNmLabel_2.Text = 'kN.m';
            app.PcEditFieldLabel = uilabel(app.UIFigure);
            app.PcEditFieldLabel.Text = 'Pc';
            app.PcEditField = uieditfield(app.UIFigure, 'numeric');
            app.PcEditField.AllowEmpty = 'on';
            app.PcEditField.Editable = 'off';
            app.PcEditField.Value = [];
            app.McEditFieldLabel = uilabel(app.UIFigure);
            app.McEditFieldLabel.Text = 'Mc';
            app.McEditField = uieditfield(app.UIFigure, 'numeric');
            app.McEditField.AllowEmpty = 'on';
            app.McEditField.Editable = 'off';
            app.McEditField.Value = [];
            app.kNLabel_4 = uilabel(app.UIFigure);
            app.kNLabel_4.Text = 'kN';
            app.kNmLabel_3 = uilabel(app.UIFigure);
            app.kNmLabel_3.Text = 'kN.m';
            app.hnEditFieldLabel = uilabel(app.UIFigure);
            app.hnEditFieldLabel.Text = 'hn';
            app.hnEditField = uieditfield(app.UIFigure, 'numeric');
            app.hnEditField.AllowEmpty = 'on';
            app.hnEditField.Editable = 'off';
            app.hnEditField.Value = [];
            app.ZpnSteelEditFieldLabel = uilabel(app.UIFigure);
            app.ZpnSteelEditFieldLabel.Text = 'Zpn,Steel';
            app.ZpnSteelEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZpnSteelEditField.AllowEmpty = 'on';
            app.ZpnSteelEditField.Editable = 'off';
            app.ZpnSteelEditField.Value = [];
            app.ZpnConcreteEditFieldLabel = uilabel(app.UIFigure);
            app.ZpnConcreteEditFieldLabel.Text = 'Zpn,Concrete';
            app.ZpnConcreteEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZpnConcreteEditField.AllowEmpty = 'on';
            app.ZpnConcreteEditField.Editable = 'off';
            app.ZpnConcreteEditField.Value = [];
            app.mm3Label_3 = uilabel(app.UIFigure);
            app.mm3Label_3.Text = 'mm3';
            app.mm3Label_4 = uilabel(app.UIFigure);
            app.mm3Label_4.Text = 'mm3';
            app.mmLabel = uilabel(app.UIFigure);
            app.mmLabel.Text = 'mm';
            app.MdEditFieldLabel = uilabel(app.UIFigure);
            app.MdEditFieldLabel.Text = 'Md';
            app.MdEditField = uieditfield(app.UIFigure, 'numeric');
            app.MdEditField.AllowEmpty = 'on';
            app.MdEditField.Editable = 'off';
            app.MdEditField.Value = [];
            app.kNmLabel_4 = uilabel(app.UIFigure);
            app.kNmLabel_4.Text = 'kN.m';
            app.PdEditFieldLabel = uilabel(app.UIFigure);
            app.PdEditFieldLabel.Text = 'Pd';
            app.PdEditField = uieditfield(app.UIFigure, 'numeric');
            app.PdEditField.AllowEmpty = 'on';
            app.PdEditField.Editable = 'off';
            app.PdEditField.Value = [];
            app.kNLabel_5 = uilabel(app.UIFigure);
            app.kNLabel_5.Text = 'kN';
            app.AuthorsMrANTONYRAJTDrHJaneHelenaHLabel = uilabel(app.UIFigure);
            app.AuthorsMrANTONYRAJTDrHJaneHelenaHLabel.Text = 'Authors: Mr. Antony Raj T, Dr.H.Jane Helena H, College of Engineering, Anna University, Chennai, 600 025';
            app.NoteLabel = uilabel(app.UIFigure);
            app.NoteLabel.Text = 'Note';
            app.CalculationsasperEN1994112004ELabel = uilabel(app.UIFigure);
            app.CalculationsasperEN1994112004ELabel.Text = '1.Calculations as per EN 1994-1-1:2004(E)';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '2. Eccentricity is not considered(i.e) in further version, the GUI will be updated by considering Eccentricity';
            app.OnlyAxialLoadisConsideredLabel = uilabel(app.UIFigure);
            app.OnlyAxialLoadisConsideredLabel.Text = '3. Only Axial Load is Considered';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_CFSTCOLUMNS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end